classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Button_9                 matlab.ui.control.Button
        Button_8                 matlab.ui.control.Button
        Button_7                 matlab.ui.control.Button
        Button_6                 matlab.ui.control.Button
        Button_5                 matlab.ui.control.Button
        Button_4                 matlab.ui.control.Button
        Step3Label               matlab.ui.control.Label
        Step2Label               matlab.ui.control.Label
        Step1Label               matlab.ui.control.Label
        Step16Step2Step3startoperationtxtLabel  matlab.ui.control.Label
        Label_7                  matlab.ui.control.Label
        Label_6                  matlab.ui.control.Label
        Label_5                  matlab.ui.control.Label
        Label_4                  matlab.ui.control.Label
        Label_3                  matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        Button_3                 matlab.ui.control.Button
        Button_2                 matlab.ui.control.Button
        Button                   matlab.ui.control.Button
        Image_6                  matlab.ui.control.Image
        Image_5                  matlab.ui.control.Image
        Image_4                  matlab.ui.control.Image
        Image_3                  matlab.ui.control.Image
        Image_2                  matlab.ui.control.Image
        Image                    matlab.ui.control.Image
        Panel                    matlab.ui.container.Panel
        startButton              matlab.ui.control.Button
        PrincipalInterestUIAxes  matlab.ui.control.UIAxes
        HTML                     matlab.ui.control.HTML
    end

    
    properties (Access = private)
        color_inapp
        cubemap
    end
    
    methods (Access = public)
        
        function I = drawPic(app, i)
            I = zeros(900,900,3);
            for j = 1 : 3
                for k = 1 : 3
                    for m = 1 : 3
                        I((j-1)*300+1:j*300, (k-1)*300+1:k*300, m) = ones(300,300,1) * app.color_inapp(app.cubemap(i,j,k),m) / 255;
                    end
                end
            end
        end
    end
    
    methods (Access = private)
        
        function results = createCube(app)
                        plot3(app.PrincipalInterestUIAxes,0,0,0)
            x = [-1  1  1 -1 -1 -1;  1  1 -1 -1  1  1;  1  1 -1 -1  1  1; -1  1  1 -1 -1 -1];
            y = [-1 -1  1 -1 -1 -1;-1  1  1  1 -1 -1; -1  1  1  1  1  1; -1 -1  1 -1  1  1]; 
            z = [0 0 0 0 0 2; 0 0 0 0 0 2; 2 2 2 2 0 2; 2 2 2 2 0 2];
            
            X(:,:,3) = x+2;
            X(:,:,2) = x;
            X(:,:,1) = x-2;
            
            Y(:,:,3) = y+2;
            Y(:,:,2) = y;
            Y(:,:,1) = y-2;
            
            Z(:,:,3) = z+2;
            Z(:,:,2) = z;
            Z(:,:,1) = z-2;
            
            Org(:,:,1) = [2 0 2 0 2 2];
            Org(:,:,2) = [0 0 0.92 2 2 2];
            Org(:,:,3) = [0 2 0 0 0 2];
  
            
            global f1 f2 f3 f4 f5 f6 f7 f8 f9 m1 m2 m3 m4 m6 m7 m8 m9 b1 b2 b3 b4 b5 b6 b7 b8 b9
            f1 = patch(app.PrincipalInterestUIAxes, X(:,:,1), Y(:,:,3), Z(:,:,3), Org);
            f2 = patch(app.PrincipalInterestUIAxes, X(:,:,1), Y(:,:,2), Z(:,:,3), Org);
            f3 = patch(app.PrincipalInterestUIAxes, X(:,:,1), Y(:,:,1), Z(:,:,3), Org);
            f4 = patch(app.PrincipalInterestUIAxes, X(:,:,1), Y(:,:,3), Z(:,:,2), Org);
            f5 = patch(app.PrincipalInterestUIAxes, X(:,:,1), Y(:,:,2), Z(:,:,2), Org);
            f6 = patch(app.PrincipalInterestUIAxes, X(:,:,1), Y(:,:,1), Z(:,:,2), Org);
            f7 = patch(app.PrincipalInterestUIAxes, X(:,:,1), Y(:,:,3), Z(:,:,1), Org);
            f8 = patch(app.PrincipalInterestUIAxes, X(:,:,1), Y(:,:,2), Z(:,:,1), Org);
            f9 = patch(app.PrincipalInterestUIAxes, X(:,:,1), Y(:,:,1), Z(:,:,1), Org);
            
            m1 = patch(app.PrincipalInterestUIAxes, X(:,:,2), Y(:,:,3), Z(:,:,3), Org);
            m2 = patch(app.PrincipalInterestUIAxes, X(:,:,2), Y(:,:,2), Z(:,:,3), Org);
            m3 = patch(app.PrincipalInterestUIAxes, X(:,:,2), Y(:,:,1), Z(:,:,3), Org);
            m4 = patch(app.PrincipalInterestUIAxes, X(:,:,2), Y(:,:,3), Z(:,:,2), Org);
            m6 = patch(app.PrincipalInterestUIAxes, X(:,:,2), Y(:,:,1), Z(:,:,2), Org);
            m7 = patch(app.PrincipalInterestUIAxes, X(:,:,2), Y(:,:,3), Z(:,:,1), Org);
            m8 = patch(app.PrincipalInterestUIAxes, X(:,:,2), Y(:,:,2), Z(:,:,1), Org);
            m9 = patch(app.PrincipalInterestUIAxes, X(:,:,2), Y(:,:,1), Z(:,:,1), Org);
            
            b1 = patch(app.PrincipalInterestUIAxes, X(:,:,3), Y(:,:,3), Z(:,:,3), Org);
            b2 = patch(app.PrincipalInterestUIAxes, X(:,:,3), Y(:,:,2), Z(:,:,3), Org);
            b3 = patch(app.PrincipalInterestUIAxes, X(:,:,3), Y(:,:,1), Z(:,:,3), Org);
            b4 = patch(app.PrincipalInterestUIAxes, X(:,:,3), Y(:,:,3), Z(:,:,2), Org);
            b5 = patch(app.PrincipalInterestUIAxes, X(:,:,3), Y(:,:,2), Z(:,:,2), Org);
            b6 = patch(app.PrincipalInterestUIAxes, X(:,:,3), Y(:,:,1), Z(:,:,2), Org);
            b7 = patch(app.PrincipalInterestUIAxes, X(:,:,3), Y(:,:,3), Z(:,:,1), Org);
            b8 = patch(app.PrincipalInterestUIAxes, X(:,:,3), Y(:,:,2), Z(:,:,1), Org);
            b9 = patch(app.PrincipalInterestUIAxes, X(:,:,3), Y(:,:,1), Z(:,:,1), Org);
            
            file = fopen("rgb.txt");
            cell = textscan(file, "%f %f %f");
            fclose(file);
            color = zeros(6,3);
            color(1,:) = [cell{1,1}(1,:) cell{1,2}(1,:) cell{1,3}(1,:)];
            color(2,:) = [cell{1,1}(2,:) cell{1,2}(2,:) cell{1,3}(2,:)];
            color(3,:) = [cell{1,1}(3,:) cell{1,2}(3,:) cell{1,3}(3,:)];
            color(4,:) = [cell{1,1}(4,:) cell{1,2}(4,:) cell{1,3}(4,:)];
            color(5,:) = [cell{1,1}(5,:) cell{1,2}(5,:) cell{1,3}(5,:)];
            color(6,:) = [cell{1,1}(6,:) cell{1,2}(6,:) cell{1,3}(6,:)];
            color = color / 255;
            
            file = fopen("input.txt");
            cubePlace = textscan(file, "%d %d %d");
            fclose(file);
            cubeFace = zeros(6,9);
            
            cubeFace(1,:) = [cubePlace{1,1}(1,:),  cubePlace{1,2}(1,:), cubePlace{1,3}(1,:), cubePlace{1,1}(2,:),  cubePlace{1,2}(2,:), cubePlace{1,3}(2,:), cubePlace{1,1}(3,:),  cubePlace{1,2}(3,:), cubePlace{1,3}(3,:)];
            cubeFace(2,:) = [cubePlace{1,1}(4,:),  cubePlace{1,2}(4,:), cubePlace{1,3}(4,:), cubePlace{1,1}(5,:),  cubePlace{1,2}(5,:), cubePlace{1,3}(5,:), cubePlace{1,1}(6,:),  cubePlace{1,2}(6,:), cubePlace{1,3}(6,:)];
            cubeFace(3,:) = [cubePlace{1,1}(7,:),  cubePlace{1,2}(7,:), cubePlace{1,3}(7,:), cubePlace{1,1}(8,:),  cubePlace{1,2}(8,:), cubePlace{1,3}(8,:), cubePlace{1,1}(9,:),  cubePlace{1,2}(9,:), cubePlace{1,3}(9,:)];
            cubeFace(4,:) = [cubePlace{1,1}(10,:),  cubePlace{1,2}(10,:), cubePlace{1,3}(10,:), cubePlace{1,1}(11,:),  cubePlace{1,2}(11,:), cubePlace{1,3}(11,:), cubePlace{1,1}(12,:),  cubePlace{1,2}(12,:), cubePlace{1,3}(12,:)];
            cubeFace(5,:) = [cubePlace{1,1}(13,:),  cubePlace{1,2}(13,:), cubePlace{1,3}(13,:), cubePlace{1,1}(14,:),  cubePlace{1,2}(14,:), cubePlace{1,3}(14,:), cubePlace{1,1}(15,:),  cubePlace{1,2}(15,:), cubePlace{1,3}(15,:)];
            cubeFace(6,:) = [cubePlace{1,1}(16,:),  cubePlace{1,2}(16,:), cubePlace{1,3}(16,:), cubePlace{1,1}(17,:),  cubePlace{1,2}(17,:), cubePlace{1,3}(17,:), cubePlace{1,1}(18,:),  cubePlace{1,2}(18,:), cubePlace{1,3}(18,:)];
            
            b1.CData(6,1,:) = color(cubeFace(1,1),:);
            b2.CData(6,1,:) = color(cubeFace(1,2),:);
            b3.CData(6,1,:) = color(cubeFace(1,3),:);
            m1.CData(6,1,:) = color(cubeFace(1,4),:);
            m2.CData(6,1,:) = color(cubeFace(1,5),:);
            m3.CData(6,1,:) = color(cubeFace(1,6),:);
            f1.CData(6,1,:) = color(cubeFace(1,7),:);
            f2.CData(6,1,:) = color(cubeFace(1,8),:);
            f3.CData(6,1,:) = color(cubeFace(1,9),:);
            
            f1.CData(4,1,:) = color(cubeFace(2,1),:);
            f2.CData(4,1,:) = color(cubeFace(2,2),:);
            f3.CData(4,1,:) = color(cubeFace(2,3),:);
            f4.CData(4,1,:) = color(cubeFace(2,4),:);
            f5.CData(4,1,:) = color(cubeFace(2,5),:);
            f6.CData(4,1,:) = color(cubeFace(2,6),:);
            f7.CData(4,1,:) = color(cubeFace(2,7),:);
            f8.CData(4,1,:) = color(cubeFace(2,8),:);
            f9.CData(4,1,:) = color(cubeFace(2,9),:);
            
            f7.CData(5,1,:) = color(cubeFace(3,1),:);
            f8.CData(5,1,:) = color(cubeFace(3,2),:);
            f9.CData(5,1,:) = color(cubeFace(3,3),:);
            m7.CData(5,1,:) = color(cubeFace(3,4),:);
            m8.CData(5,1,:) = color(cubeFace(3,5),:);
            m9.CData(5,1,:) = color(cubeFace(3,6),:);
            b7.CData(5,1,:) = color(cubeFace(3,7),:);
            b8.CData(5,1,:) = color(cubeFace(3,8),:);
            b9.CData(5,1,:) = color(cubeFace(3,9),:);
            
            b7.CData(2,1,:) = color(cubeFace(4,1),:);
            b8.CData(2,1,:) = color(cubeFace(4,2),:);
            b9.CData(2,1,:) = color(cubeFace(4,3),:);
            b4.CData(2,1,:) = color(cubeFace(4,4),:);
            b5.CData(2,1,:) = color(cubeFace(4,5),:);
            b6.CData(2,1,:) = color(cubeFace(4,6),:);
            b1.CData(2,1,:) = color(cubeFace(4,7),:);
            b2.CData(2,1,:) = color(cubeFace(4,8),:);
            b3.CData(2,1,:) = color(cubeFace(4,9),:);
            
            b1.CData(3,1,:) = color(cubeFace(5,1),:);
            m1.CData(3,1,:) = color(cubeFace(5,2),:);
            f1.CData(3,1,:) = color(cubeFace(5,3),:);
            b4.CData(3,1,:) = color(cubeFace(5,4),:);
            m4.CData(3,1,:) = color(cubeFace(5,5),:);
            f4.CData(3,1,:) = color(cubeFace(5,6),:);
            b7.CData(3,1,:) = color(cubeFace(5,7),:);
            m7.CData(3,1,:) = color(cubeFace(5,8),:);
            f7.CData(3,1,:) = color(cubeFace(5,9),:);
            
            f3.CData(1,1,:) = color(cubeFace(6,1),:);
            m3.CData(1,1,:) = color(cubeFace(6,2),:);
            b3.CData(1,1,:) = color(cubeFace(6,3),:);
            f6.CData(1,1,:) = color(cubeFace(6,4),:);
            m6.CData(1,1,:) = color(cubeFace(6,5),:);
            b6.CData(1,1,:) = color(cubeFace(6,6),:);
            f9.CData(1,1,:) = color(cubeFace(6,7),:);
            m9.CData(1,1,:) = color(cubeFace(6,8),:);
            b9.CData(1,1,:) = color(cubeFace(6,9),:);
                                   
            axis(app.PrincipalInterestUIAxes,[-5 5 -5 5 -5 5]);
        end
        
        function results = clockwiseturn90(app, face, axis)
            core = [0 0 1]; 
            angle = -11.25;
            vector = face(:);
            for i=1:8
                rotate(vector, axis, angle, core)
                pause(0.01)
            end
            results = rot90(rot90(rot90(face)));
        end
        
        function results = turnCube(app, op, times)
            global f1 f2 f3 f4 f5 f6 f7 f8 f9 m1 m2 m3 m4 m6 m7 m8 m9 b1 b2 b3 b4 b5 b6 b7 b8 b9
            if op == "U"
                for i = 1:times
                    face = [b1 b2 b3; m1 m2 m3; f1 f2 f3];
                    rst = clockwiseturn90(app, face, [0 0 1]);
                    b1 = rst(1,1); b2 = rst(1,2); b3 = rst(1,3);
                    m1 = rst(2,1); m2 = rst(2,2); m3 = rst(2,3);
                    f1 = rst(3,1); f2 = rst(3,2); f3 = rst(3,3);
                end
            elseif op == "F"
                for i = 1:times
                    face = [f1 f2 f3; f4 f5 f6; f7 f8 f9];
                    rst = clockwiseturn90(app, face, [-1 0 0]);
                    f1 = rst(1,1); f2 = rst(1,2); f3 = rst(1,3);
                    f4 = rst(2,1); f5 = rst(2,2); f6 = rst(2,3);
                    f7 = rst(3,1); f8 = rst(3,2); f9 = rst(3,3);
                end
              
            elseif op == "D"
                for i = 1:times
                    face = [f7 f8 f9; m7 m8 m9; b7 b8 b9];
                    rst = clockwiseturn90(app, face, [0 0 -1]);
                    f7 = rst(1,1); f8 = rst(1,2); f9 = rst(1,3);
                    m7 = rst(2,1); m8 = rst(2,2); m9 = rst(2,3);
                    b7 = rst(3,1); b8 = rst(3,2); b9 = rst(3,3);
                end
                
            elseif op == "B"
                for i = 1:times
                    face = [b7 b8 b9; b4 b5 b6; b1 b2 b3];
                    rst = clockwiseturn90(app, face, [1 0 0]);
                    b7 = rst(1,1); b8 = rst(1,2); b9 = rst(1,3);
                    b4 = rst(2,1); b5 = rst(2,2); b6 = rst(2,3);
                    b1 = rst(3,1); b2 = rst(3,2); b3 = rst(3,3);
                end
                
            elseif op == "L"
                for i = 1:times
                    face = [b1 m1 f1; b4 m4 f4; b7 m7 f7];
                    rst = clockwiseturn90(app, face, [0 1 0]);
                    b1 = rst(1,1); m1 = rst(1,2); f1 = rst(1,3);
                    b4 = rst(2,1); m4 = rst(2,2); f4 = rst(2,3);
                    b7 = rst(3,1); m7 = rst(3,2); f7 = rst(3,3);
                end
                
            elseif op == "R"
                for i = 1:times
                    face = [f3 m3 b3; f6 m6 b6; f9 m9 b9];
                    rst = clockwiseturn90(app, face, [0 -1 0]);
                    f3 = rst(1,1); m3 = rst(1,2); b3 = rst(1,3);
                    f6 = rst(2,1); m6 = rst(2,2); b6 = rst(2,3);
                    f9 = rst(3,1); m9 = rst(3,2); b9 = rst(3,3);
                end
            else
                warning("Wrong Operation!");
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.cubemap = zeros(6:3:3);
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            [file, path] = uigetfile("*.*");
            if file ~= 0
                I = imread(fullfile(path, file));
                try
                    app.Image.ImageSource = cut_square(I);
                    imwrite(app.Image.ImageSource, "1.png");
                catch
                    app.Image.ImageSource = I;
                    warning("图片1:魔方识别错误,请换取图片输入");
                end
            end
        end

        % Image clicked function: Image_2
        function Image_2Clicked(app, event)
            [file, path] = uigetfile("*.*");
            if file ~= 0
                I = imread(fullfile(path, file));
                try
                    app.Image_2.ImageSource = cut_square(I);
                    imwrite(app.Image_2.ImageSource, "2.png");
                catch
                    app.Image_2.ImageSource = I;
                    warning("图片2:魔方识别错误,请换取图片输入");
                end
            end
        end

        % Image clicked function: Image_3
        function Image_3Clicked(app, event)
            [file, path] = uigetfile("*.*");
            if file ~= 0
                I = imread(fullfile(path, file));
                try
                    app.Image_3.ImageSource = cut_square(I);
                    imwrite(app.Image_3.ImageSource, "3.png");
                catch
                    app.Image_3.ImageSource = I;
                    warning("图片3:魔方识别错误,请换取图片输入");
                end
            end
        end

        % Image clicked function: Image_4
        function Image_4Clicked(app, event)
            [file, path] = uigetfile("*.*");
            if file ~= 0
                I = imread(fullfile(path, file));
                try
                    app.Image_4.ImageSource = cut_square(I);
                    imwrite(app.Image_4.ImageSource, "4.png");
                catch
                    app.Image_4.ImageSource = I;
                    warning("图片4:魔方识别错误,请换取图片输入");
                end
            end
        end

        % Image clicked function: Image_5
        function Image_5Clicked(app, event)
            [file, path] = uigetfile("*.*");
            if file ~= 0
                I = imread(fullfile(path, file));
                try
                    app.Image_5.ImageSource = cut_square(I);
                    imwrite(app.Image_5.ImageSource, "5.png");
                catch
                    app.Image_5.ImageSource = I;
                    warning("图片5:魔方识别错误,请换取图片输入");
                end
            end
        end

        % Image clicked function: Image_6
        function Image_6Clicked(app, event)
            [file, path] = uigetfile("*.*");
            if file ~= 0
                I = imread(fullfile(path, file));
                try
                    app.Image_6.ImageSource = cut_square(I);
                    imwrite(app.Image_6.ImageSource, "6.png");
                catch
                    app.Image_6.ImageSource = I;
                    warning("图片6:魔方识别错误,请换取图片输入");
                end
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [file, path] = uigetfile('*.*','MultiSelect', 'on');
            sz = size(file);
            if sz(2) == 6
                I1 = imread(fullfile(path, file{1}));
                I2 = imread(fullfile(path, file{2}));
                I3 = imread(fullfile(path, file{3}));
                I4 = imread(fullfile(path, file{4}));
                I5 = imread(fullfile(path, file{5}));
                I6 = imread(fullfile(path, file{6}));

                try
                    app.Image.ImageSource = cut_square(I1);
                    imwrite(app.Image.ImageSource, "1.png");
                catch
                    app.Image.ImageSource = I1;
                    warning("图片1:魔方识别错误,请换取图片输入");
                end
                
                try
                    app.Image_2.ImageSource = cut_square(I2);
                    imwrite(app.Image_2.ImageSource, "2.png");
                catch
                    app.Image_2.ImageSource = I2;
                    warning("图片2:魔方识别错误,请换取图片输入");
                end
                
                try
                    app.Image_3.ImageSource = cut_square(I3);
                    imwrite(app.Image_3.ImageSource, "3.png");
                catch
                    app.Image_3.ImageSource = I3;
                    warning("图片3:魔方识别错误,请换取图片输入");
                end
                
                try
                    app.Image_4.ImageSource = cut_square(I4);
                    imwrite(app.Image_4.ImageSource, "4.png");
                catch
                    app.Image_4.ImageSource = I4;
                    warning("图片4:魔方识别错误,请换取图片输入");
                end
                
                try
                    app.Image_5.ImageSource = cut_square(I5);
                    imwrite(app.Image_5.ImageSource, "5.png");
                catch
                    app.Image_5.ImageSource = I5;
                    warning("图片5:魔方识别错误,请换取图片输入");
                end
                
                try
                    app.Image_6.ImageSource = cut_square(I6);
                    imwrite(app.Image_6.ImageSource, "6.png");
                catch
                    app.Image_6.ImageSource = I6;
                    warning("图片6:魔方识别错误,请换取图片输入");
                end
                
            else 
                warning("上传图片个数不为6张，请重新上传")
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            color_detection;
            
            file = fopen("rgb.txt");
            colorRpg = textscan(file, "%f %f %f");
            fclose(file);
            
            app.color_inapp = zeros(6,3);
            
            app.color_inapp(1,:) = [colorRpg{1,1}(1,:), colorRpg{1,2}(1,:), colorRpg{1,3}(1,:)];
            app.color_inapp(2,:) = [colorRpg{1,1}(2,:), colorRpg{1,2}(2,:), colorRpg{1,3}(2,:)];
            app.color_inapp(3,:) = [colorRpg{1,1}(3,:), colorRpg{1,2}(3,:), colorRpg{1,3}(3,:)];
            app.color_inapp(4,:) = [colorRpg{1,1}(4,:), colorRpg{1,2}(4,:), colorRpg{1,3}(4,:)];
            app.color_inapp(5,:) = [colorRpg{1,1}(5,:), colorRpg{1,2}(5,:), colorRpg{1,3}(5,:)];
            app.color_inapp(6,:) = [colorRpg{1,1}(6,:), colorRpg{1,2}(6,:), colorRpg{1,3}(6,:)];
            
            file = fopen("input.txt");
            cubePlace = textscan(file, "%d %d %d");
            fclose(file);
            
            app.cubemap = zeros(6:3:3);
            app.cubemap(1,:,:) = [cubePlace{1,1}(1,:),  cubePlace{1,2}(1,:), cubePlace{1,3}(1,:);
                cubePlace{1,1}(2,:),  cubePlace{1,2}(2,:), cubePlace{1,3}(2,:);
                cubePlace{1,1}(3,:),  cubePlace{1,2}(3,:), cubePlace{1,3}(3,:)];
            app.cubemap(2,:,:) = [cubePlace{1,1}(4,:),  cubePlace{1,2}(4,:), cubePlace{1,3}(4,:);
                cubePlace{1,1}(5,:),  cubePlace{1,2}(5,:), cubePlace{1,3}(5,:);
                cubePlace{1,1}(6,:),  cubePlace{1,2}(6,:), cubePlace{1,3}(6,:)];
            app.cubemap(3,:,:) = [cubePlace{1,1}(7,:),  cubePlace{1,2}(7,:), cubePlace{1,3}(7,:);
                cubePlace{1,1}(8,:),  cubePlace{1,2}(8,:), cubePlace{1,3}(8,:);
                cubePlace{1,1}(9,:),  cubePlace{1,2}(9,:), cubePlace{1,3}(9,:)];
            app.cubemap(4,:,:) = [cubePlace{1,1}(10,:),  cubePlace{1,2}(10,:), cubePlace{1,3}(10,:);
                cubePlace{1,1}(11,:),  cubePlace{1,2}(11,:), cubePlace{1,3}(11,:);
                cubePlace{1,1}(12,:),  cubePlace{1,2}(12,:), cubePlace{1,3}(12,:)];
            app.cubemap(5,:,:) = [cubePlace{1,1}(13,:),  cubePlace{1,2}(13,:), cubePlace{1,3}(13,:);
                cubePlace{1,1}(14,:),  cubePlace{1,2}(14,:), cubePlace{1,3}(14,:);
                cubePlace{1,1}(15,:),  cubePlace{1,2}(15,:), cubePlace{1,3}(15,:)];
            app.cubemap(6,:,:) = [cubePlace{1,1}(16,:),  cubePlace{1,2}(16,:), cubePlace{1,3}(16,:);
                cubePlace{1,1}(17,:),  cubePlace{1,2}(17,:), cubePlace{1,3}(17,:);
                cubePlace{1,1}(18,:),  cubePlace{1,2}(18,:), cubePlace{1,3}(18,:)];
            
            app.Image.ImageSource = app.drawPic(1);
            app.Image_2.ImageSource = app.drawPic(2);
            app.Image_3.ImageSource = app.drawPic(3);
            app.Image_4.ImageSource = app.drawPic(4);
            app.Image_5.ImageSource = app.drawPic(5);
            app.Image_6.ImageSource = app.drawPic(6);
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            solvecube;
            createCube(app);
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            file = fopen("operation.txt");
            in = textscan(file, "%c %d");
            fclose(file);
            ops = in{1,1};
            times = in{1,2};
            l = size(ops);
            l = l(:,1);
            
            for i = 1:l
                turnCube(app, ops(i,:), times(i,:));
            end
            
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.Image.ImageSource = imrotate(app.Image.ImageSource, 90);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.Image_2.ImageSource = imrotate(app.Image_2.ImageSource, 90);
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.Image_3.ImageSource = imrotate(app.Image_3.ImageSource, 90);
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.Image_4.ImageSource = imrotate(app.Image_4.ImageSource, 90);
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.Image_5.ImageSource = imrotate(app.Image_5.ImageSource, 90);
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            app.Image_6.ImageSource = imrotate(app.Image_6.ImageSource, 90);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HTML = uihtml(app.UIFigure);
            app.HTML.HTMLSource = 'back.html';
            app.Panel = uipanel(app.UIFigure);
            app.PrincipalInterestUIAxes = uiaxes(app.Panel);
            title(app.PrincipalInterestUIAxes, 'Solution Demonstration')
            app.PrincipalInterestUIAxes.XTick = [];
            app.PrincipalInterestUIAxes.YTick = [];
            app.PrincipalInterestUIAxes.YTickLabel = '';
            app.PrincipalInterestUIAxes.ZTick = [];
            app.startButton = uibutton(app.Panel, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = '1.jpg';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.Image_2.ImageSource = '2.jpg';
            app.Image_3 = uiimage(app.UIFigure);
            app.Image_3.ImageClickedFcn = createCallbackFcn(app, @Image_3Clicked, true);
            app.Image_3.ImageSource = '3.jpg';
            app.Image_4 = uiimage(app.UIFigure);
            app.Image_4.ImageClickedFcn = createCallbackFcn(app, @Image_4Clicked, true);
            app.Image_4.ImageSource = '4.jpg';
            app.Image_5 = uiimage(app.UIFigure);
            app.Image_5.ImageClickedFcn = createCallbackFcn(app, @Image_5Clicked, true);
            app.Image_5.ImageSource = '5.jpg';
            app.Image_6 = uiimage(app.UIFigure);
            app.Image_6.ImageClickedFcn = createCallbackFcn(app, @Image_6Clicked, true);
            app.Image_6.ImageSource = '6.jpg';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '魔方提取';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '颜色识别';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '魔方求解';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '魔方求解器';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'图片1'; '顶面'};
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = {'图片2'; '正面'};
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = {'图片3'; '底面'};
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = {'图片4'; '背面'};
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = {'图片5'; '左面'};
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = {'图片6'; '右面'};
            app.Step16Step2Step3startoperationtxtLabel = uilabel(app.UIFigure);
            app.Step16Step2Step3startoperationtxtLabel.Text = {'使用说明：'; 'Step1: 可以点击魔方提取然'; '后依次选择 6 个图片上传图'; '片，也可以点击图片自行浏'; '览文件夹逐个上传'; 'Step2: 点击颜色识别进行识'; '别结束后会输出对应的识别'; 'Step3: 点击魔方求解进行求'; '解和演示，求解完成后会弹'; '出建模好的魔方，点击“start”'; '后进行求解过程演示，也可以'; '在"operation.txt"中查看结果。'};
            app.Step1Label = uilabel(app.UIFigure);
            app.Step1Label.Text = 'Step1:';
            app.Step2Label = uilabel(app.UIFigure);
            app.Step2Label.Text = 'Step2:';
            app.Step3Label = uilabel(app.UIFigure);
            app.Step3Label.Text = 'Step3:';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '翻转';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '翻转';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '翻转';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '翻转';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '翻转';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '翻转';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end