classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Button_9                 matlab.ui.control.Button
        Button_8                 matlab.ui.control.Button
        Button_7                 matlab.ui.control.Button
        Button_6                 matlab.ui.control.Button
        Button_5                 matlab.ui.control.Button
        Button_4                 matlab.ui.control.Button
        Step3Label               matlab.ui.control.Label
        Step2Label               matlab.ui.control.Label
        Step1Label               matlab.ui.control.Label
        Step16Step2Step3startoperationtxtLabel  matlab.ui.control.Label
        Button_3                 matlab.ui.control.Button
        Button_2                 matlab.ui.control.Button
        Button                   matlab.ui.control.Button
        Label_5                  matlab.ui.control.Label
        Label_7                  matlab.ui.control.Label
        Label_6                  matlab.ui.control.Label
        Label_4                  matlab.ui.control.Label
        Label_3                  matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        Image_6                  matlab.ui.control.Image
        Image_5                  matlab.ui.control.Image
        Image_4                  matlab.ui.control.Image
        Image_3                  matlab.ui.control.Image
        Image_2                  matlab.ui.control.Image
        Image                    matlab.ui.control.Image
        Panel                    matlab.ui.container.Panel
        startButton              matlab.ui.control.Button
        PrincipalInterestUIAxes  matlab.ui.control.UIAxes
        HTML                     matlab.ui.control.HTML
    end

    
    properties (Access = private)
        old_1 % 原图
        old_2
        old_3
        old_4
        old_5
        old_6
        Img_1 % Img_1
        Img_2 % Img_2
        Img_3 % Img_3
        Img_4 % Img_4
        Img_5 % Img_5
        Img_6 % Img_6
        CubeDisplay % 旋转展示程序
        replace % 颜色替换窗口
        mat % 每个面的颜色
        rgbTable % 颜色字典
    end

    properties (Access = public)
        colorlist
    end
    
    methods (Access = public)
        
        function Img = func(app,i)
            Img = zeros(900,900,3);
            for j = 1 : 3
                for k = 1 : 3
                    for m = 1 : 3
                        Img((j-1)*300+1:j*300, (k-1)*300+1:k*300, m) = ones(300,300,1) * app.rgbTable(app.mat(i,j,k),m) / 255;
                    end
                end
            end
        end
        
        function results = createCube(app)
            x = [-0.5  0.5  0.5 -0.5 -0.5 -0.5;  0.5  0.5 -0.5 -0.5  0.5  0.5;  0.5  0.5 -0.5 -0.5  0.5  0.5; -0.5  0.5  0.5 -0.5 -0.5 -0.5];
            y = [-0.5 -0.5  0.5 -0.5 -0.5 -0.5;-0.5  0.5  0.5  0.5 -0.5 -0.5; -0.5  0.5  0.5  0.5  0.5  0.5; -0.5 -0.5  0.5 -0.5  0.5  0.5]; 
            z = [0 0 0 0 0 1; 0 0 0 0 0 1; 1 1 1 1 0 1; 1 1 1 1 0 1];
            xup = ones(size(x))+x;
            yup = ones(size(x))+y;
            xdown = -1*ones(size(x))+x;
            ydown = -1*ones(size(x))+y;
            zup = ones(size(z))+z;%zup
            zdown = -1*ones(size(z))+z; %zdouwn   
            Orgcell1 = [1 0 1  0  1 1 ];        
            Orgcell2 = [0 0 0.46 1  1 1];
            Orgcell3 = [0 1 0  0  0 1 ];
            Org1(:,1) = Orgcell1;
            Org2(:,1) = Orgcell2;
            Org3(:,1) = Orgcell3;
            Org(1,:,:)=[Org1 Org2 Org3 ];


            plot3(app.PrincipalInterestUIAxes,0,0,0)
            %%魔方共27个小块，hn代表其中某一小块
            global h0 h1 h2 h3 h4 h5 h6 h7 h8 h10 h11 h12 h13 h14 h15 h16 h17 h18 h20 h21 h22 h23 h24 h25 h26 h27 h28
            h0= patch(app.PrincipalInterestUIAxes, xdown,yup,z, Org);
            h1 = patch(app.PrincipalInterestUIAxes,x,yup,z, Org);  
            h2 = patch(app.PrincipalInterestUIAxes,xup,yup,z, Org);   
            h3 = patch(app.PrincipalInterestUIAxes,xdown,y,z, Org);  
            h4 = patch(app.PrincipalInterestUIAxes,x,y,z, Org);    
            h5 = patch(app.PrincipalInterestUIAxes,xup,y,z, Org);  
            h6 = patch(app.PrincipalInterestUIAxes,xdown,ydown,z, Org);
            h7 = patch(app.PrincipalInterestUIAxes,x,ydown,z, Org);  
            h8 = patch(app.PrincipalInterestUIAxes,xup,ydown,z, Org);
            
            h10 = patch(app.PrincipalInterestUIAxes,xdown,yup,zup, Org);
            h11 = patch(app.PrincipalInterestUIAxes,x,yup,zup, Org);
            h12 = patch(app.PrincipalInterestUIAxes,xup,yup,zup, Org);
            h13 = patch(app.PrincipalInterestUIAxes,xdown,y,zup, Org);
            h14 = patch(app.PrincipalInterestUIAxes,x,y,zup, Org);
            h15 = patch(app.PrincipalInterestUIAxes,xup,y,zup, Org);
            h16 = patch(app.PrincipalInterestUIAxes,xdown,ydown,zup, Org);
            h17 = patch(app.PrincipalInterestUIAxes,x,ydown,zup, Org);
            h18 = patch(app.PrincipalInterestUIAxes,xup,ydown,zup, Org);
            
            h20 = patch(app.PrincipalInterestUIAxes,xdown,yup,zdown, Org);
            h21 = patch(app.PrincipalInterestUIAxes,x,yup,zdown, Org);
            h22 = patch(app.PrincipalInterestUIAxes,xup,yup,zdown, Org);
            h23 = patch(app.PrincipalInterestUIAxes,xdown,y,zdown, Org);
            h24 = patch(app.PrincipalInterestUIAxes,x,y,zdown, Org);
            h25 = patch(app.PrincipalInterestUIAxes,xup,y,zdown, Org);
            h26 = patch(app.PrincipalInterestUIAxes,xdown,ydown,zdown, Org);
            h27 = patch(app.PrincipalInterestUIAxes,x,ydown,zdown, Org);
            h28 = patch(app.PrincipalInterestUIAxes,xup,ydown,zdown, Org);
           
            fileid=fopen('rgb.txt');
            res=[3,inf];
            A=fscanf(fileid,"%f %f %f ",res);
            A=A';
            display(A)
            A=A(:,1:3);
            color = A/255;
            fileid=fopen('input.txt'); % 颜色代号的矩阵
            res=[9,inf];
            A=fscanf(fileid,"%d %d %d  %d %d %d  %d %d %d",res);
            A=A';
            display(A)

            face=A;
            
            h10.CData(6,1,:)=color(face(1,1),:);
            h11.CData(6,1,:)=color(face(1,2),:);
            h12.CData(6,1,:)=color(face(1,3),:);
            h13.CData(6,1,:)=color(face(1,4),:);
            h14.CData(6,1,:)=color(face(1,5),:);
            h15.CData(6,1,:)=color(face(1,6),:);
            h16.CData(6,1,:)=color(face(1,7),:);
            h17.CData(6,1,:)=color(face(1,8),:);
            h18.CData(6,1,:)=color(face(1,9),:);
            
            h16.CData(1,1,:)=color(face(2,1),:);
            h17.CData(1,1,:)=color(face(2,2),:);
            h18.CData(1,1,:)=color(face(2,3),:);
            h6.CData(1,1,:)=color(face(2,4),:);
            h7.CData(1,1,:)=color(face(2,5),:);
            h8.CData(1,1,:)=color(face(2,6),:);
            h26.CData(1,1,:)=color(face(2,7),:);
            h27.CData(1,1,:)=color(face(2,8),:);
            h28.CData(1,1,:)=color(face(2,9),:);
            
            h26.CData(5,1,:)=color(face(3,1),:);
            h27.CData(5,1,:)=color(face(3,2),:);
            h28.CData(5,1,:)=color(face(3,3),:);
            h23.CData(5,1,:)=color(face(3,4),:);
            h24.CData(5,1,:)=color(face(3,5),:);
            h25.CData(5,1,:)=color(face(3,6),:);
            h20.CData(5,1,:)=color(face(3,7),:);
            h21.CData(5,1,:)=color(face(3,8),:);
            h22.CData(5,1,:)=color(face(3,9),:);
            
            h20.CData(3,1,:)=color(face(4,1),:);
            h21.CData(3,1,:)=color(face(4,2),:);
            h22.CData(3,1,:)=color(face(4,3),:);
            h0.CData(3,1,:)=color(face(4,4),:);
            h1.CData(3,1,:)=color(face(4,5),:);
            h2.CData(3,1,:)=color(face(4,6),:);
            h10.CData(3,1,:)=color(face(4,7),:);
            h11.CData(3,1,:)=color(face(4,8),:);
            h12.CData(3,1,:)=color(face(4,9),:);
            
            h10.CData(4,1,:)=color(face(5,1),:);
            h13.CData(4,1,:)=color(face(5,2),:);
            h16.CData(4,1,:)=color(face(5,3),:);
            h0.CData(4,1,:)=color(face(5,4),:);
            h3.CData(4,1,:)=color(face(5,5),:);
            h6.CData(4,1,:)=color(face(5,6),:);
            h20.CData(4,1,:)=color(face(5,7),:);
            h23.CData(4,1,:)=color(face(5,8),:);
            h26.CData(4,1,:)=color(face(5,9),:);
            
            h18.CData(2,1,:)=color(face(6,1),:);
            h15.CData(2,1,:)=color(face(6,2),:);
            h12.CData(2,1,:)=color(face(6,3),:);
            h8.CData(2,1,:)=color(face(6,4),:);
            h5.CData(2,1,:)=color(face(6,5),:);
            h2.CData(2,1,:)=color(face(6,6),:);
            h28.CData(2,1,:)=color(face(6,7),:);
            h25.CData(2,1,:)=color(face(6,8),:);
            h22.CData(2,1,:)=color(face(6,9),:);


            %%显示的三维背景
            %axis(app.PrincipalInterestUIAxes,[-4 4 -4 4 -4 4]);                        %%
            title(app.PrincipalInterestUIAxes,'求解动作演示');                               %%
            axis(app.PrincipalInterestUIAxes,[-4 4 -4 4 -3 3]);
            set(app.PrincipalInterestUIAxes,'Color',[0.9411764705 0.9411764705 0.9411764705])%%
        end
        
        
        function ssz=rotate1(~,h,direct)
            origin=[0 0 0.5]; %魔方正中心,此为旋转中心
            theta=-11.25;%5.625;  %每次转动的角度
            H=h;
            for i=1:8
                rotate(H(:),direct,theta,origin)
                pause(0.01)   %%停顿的秒数
            end
            h11s=h(1,1);h12s=h(1,2);h13s=h(1,3);h21s=h(2,1);h22s=h(2,2);h23s=h(2,3);h31s=h(3,1);h32s=h(3,2);h33s=h(3,3);
            h(1,1)=h31s;h(1,2)=h21s;h(1,3)=h11s;h(2,1)=h32s;h(2,2)=h22s;h(2,3)=h12s;h(3,1)=h33s;h(3,2)=h23s;h(3,3)=h13s;
            ssz=h;
        end
        
        function nsz=rotate2(~,h,direct)
            origin=[0 0 0.5]; %魔方正中心,为旋转中心
            theta=11.25;  %每次转动的角度
            H=h;
            for i=1:8
                rotate(H(:),direct,theta,origin)
                pause(0.01)   %%停顿的秒数
            end
            h11s=h(1,1);h12s=h(1,2);h13s=h(1,3);h21s=h(2,1);h22s=h(2,2);h23s=h(2,3);h31s=h(3,1);h32s=h(3,2);h33s=h(3,3);
            h(1,1)=h13s;h(1,2)=h23s;h(1,3)=h33s;h(2,1)=h12s;h(2,2)=h22s;h(2,3)=h32s;h(3,1)=h11s;h(3,2)=h21s;h(3,3)=h31s;
            nsz = h;
        end
        function moveg(app,x,t)
            global h0 h1 h2 h3 h4 h5 h6 h7 h8 h10 h11 h12 h13 h14 h15 h16 h17 h18 h20 h21 h22 h23 h24 h25 h26 h27 h28
            if x==4
                if(t==3)
                    H1=[h18  h15  h12; h8 h5  h2;h28 h25  h22]; 
                    h=rotate2(app,H1,[1,0,0]);
                    h18=h(1,1);h15=h(1,2);h12=h(1,3);h8=h(2,1);h5=h(2,2);h2=h(2,3);h28=h(3,1);h25=h(3,2);h22=h(3,3);
                    return
                end
                
                for i=1:t
                    H1=[h18  h15  h12; h8 h5  h2;h28 h25  h22]; 
                    h=rotate1(app,H1,[1,0,0]);
                    h18=h(1,1);h15=h(1,2);h12=h(1,3);h8=h(2,1);h5=h(2,2);h2=h(2,3);h28=h(3,1);h25=h(3,2);h22=h(3,3);
                end
            elseif x==3
                if(t==3)
                    H3=[h16  h13  h10; h6 h3  h0;h26 h23  h20]; 
                    h=rotate1(app,H3,[1,0,0]);
                    h16=h(1,1);h13=h(1,2);h10=h(1,3);h6=h(2,1);h3=h(2,2);h0=h(2,3);h26=h(3,1);h23=h(3,2);h20=h(3,3);
                    return
                end
                
                for i=1:t
                    H3=[h16  h13  h10; h6 h3  h0;h26 h23  h20]; 
                    h=rotate2(app,H3,[1,0,0]);
                    h16=h(1,1);h13=h(1,2);h10=h(1,3);h6=h(2,1);h3=h(2,2);h0=h(2,3);h26=h(3,1);h23=h(3,2);h20=h(3,3);
                end
            elseif x==5
                if(t==3)
                    H4=[ h10 h11 h12;h0  h1  h2; h20 h21 h22];  
                    h=rotate1(app,H4,[0,-1,0]);
                    h10=h(1,1);h11=h(1,2);h12=h(1,3);h0=h(2,1);h1=h(2,2);h2=h(2,3);h20=h(3,1);h21=h(3,2);h22=h(3,3);
                    return
                end
                
                for i=1:t
                    H4=[ h10 h11 h12;h0  h1  h2; h20 h21 h22];  
                    h=rotate2(app,H4,[0,-1,0]);
                    h10=h(1,1);h11=h(1,2);h12=h(1,3);h0=h(2,1);h1=h(2,2);h2=h(2,3);h20=h(3,1);h21=h(3,2);h22=h(3,3);
                end
            elseif x==6
                if(t==3)
                    H6=[h16 h17 h18;h6 h7 h8;h26 h27 h28]; 
                    h=rotate2(app,H6,[0,-1,0]);
                    h16=h(1,1);h17=h(1,2);h18=h(1,3);h6=h(2,1);h7=h(2,2);h8=h(2,3);h26=h(3,1);h27=h(3,2);h28=h(3,3);
                    return
                end
                
                for i=1:t
                    H6=[h16 h17 h18;h6 h7 h8;h26 h27 h28]; 
                    h=rotate1(app,H6,[0,-1,0]);
                    h16=h(1,1);h17=h(1,2);h18=h(1,3);h6=h(2,1);h7=h(2,2);h8=h(2,3);h26=h(3,1);h27=h(3,2);h28=h(3,3);
                end
            elseif x==1
                if(t==3)
                    H7=[h10 h11  h12; h13 h14  h15;h16 h17  h18]; 
                    h=rotate2(app,H7,[0,0,1]);
                    h10=h(1,1);h11=h(1,2);h12=h(1,3);h13=h(2,1);h14=h(2,2);h15=h(2,3);h16=h(3,1);h17=h(3,2);h18=h(3,3);
                    return
                end
                
                for i=1:t
                    H7=[h10 h11  h12; h13 h14  h15;h16 h17  h18]; 
                    h=rotate1(app,H7,[0,0,1]);
                    h10=h(1,1);h11=h(1,2);h12=h(1,3);h13=h(2,1);h14=h(2,2);h15=h(2,3);h16=h(3,1);h17=h(3,2);h18=h(3,3);
                end
            elseif x==2
                if(t==3)
                    H9=[h20 h21 h22; h23 h24 h25;h26 h27 h28];  
                    h=rotate1(app,H9,[0,0,1]);
                    h20=h(1,1);h21=h(1,2);h22=h(1,3);h23=h(2,1);h24=h(2,2);h25=h(2,3);h26=h(3,1);h27=h(3,2);h28=h(3,3);
                    return
                end
                
                for i=1:t
                    H9=[h20 h21 h22; h23 h24 h25;h26 h27 h28];  %H9为有黄色面的九小块，Z轴下层
                    h=rotate2(app,H9,[0,0,1]);
                    h20=h(1,1);h21=h(1,2);h22=h(1,3);h23=h(2,1);h24=h(2,2);h25=h(2,3);h26=h(3,1);h27=h(3,2);h28=h(3,3);
                end
            end
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % app.Img_1 = imread('E1.jpg');
            % app.Img_2 = imread('E2.jpg');
            % app.Img_3 = imread('E3.jpg');
            % app.Img_4 = imread('E4.jpg');
            % app.Img_5 = imread('E5.jpg');
            % app.Img_6 = imread('E6.jpg');
            % app.Image.ImageSource = app.Img_1;
            % app.Image_2.ImageSource = app.Img_2;
            % app.Image_3.ImageSource = app.Img_3;
            % app.Image_4.ImageSource = app.Img_4;
            % app.Image_5.ImageSource = app.Img_5;
            % app.Image_6.ImageSource = app.Img_6;

            % 自动裁剪
            % app.Img_1 = cut_square(app.Img_1);
            % app.Img_2 = cut_square(app.Img_2);
            % app.Img_3 = cut_square(app.Img_3);
            % app.Img_4 = cut_square(app.Img_4);
            % app.Img_5 = cut_square(app.Img_5);
            % app.Img_6 = cut_square(app.Img_6);
            % app.Image.ImageSource = app.Img_1;
            % app.Image_2.ImageSource = app.Img_2;
            % app.Image_3.ImageSource = app.Img_3;
            % app.Image_4.ImageSource = app.Img_4;
            % app.Image_5.ImageSource = app.Img_5;
            % app.Image_6.ImageSource = app.Img_6;
            app.mat = zeros(6,3,3);
        end

        % Image clicked function: Image
        function ImageClicked(app, event)

            [file,path] = uigetfile('*.*');
            if isequal(file,0)
            else
                app.Img_1 = imread(fullfile(path,file));
                app.old_1 = app.Img_1;
                try
                    app.Img_1 = cut_square(app.Img_1);
                    app.Image.ImageSource = app.Img_1;
                catch
                    app.Image.ImageSource = app.Img_1;
                    warndlg('识别失败！建议您手工标定。');
                end
            end
        end

        % Image clicked function: Image_2
        function Image_2Clicked(app, event)
            
            [file,path] = uigetfile('*.*');
            if isequal(file,0)
            else
                app.Img_2 = imread(fullfile(path,file));
                app.old_2 = app.Img_2;
                try
                    app.Img_2 = cut_square(app.Img_2);
                    app.Image_2.ImageSource = app.Img_2;
                catch
                    app.Image_2.ImageSource = app.Img_2;
                    warndlg('识别失败！建议您手工标定。');
                end     
            end
        end

        % Image clicked function: Image_3
        function Image_3Clicked(app, event)
            
            [file,path] = uigetfile('*.*');
            if isequal(file,0)
            else
                app.Img_3 = imread(fullfile(path,file));
                app.old_3 = app.Img_3;
                try
                    app.Img_3 = cut_square(app.Img_3);
                    app.Image_3.ImageSource = app.Img_3;
                catch
                    app.Image_3.ImageSource = app.Img_3;
                    warndlg('识别失败！建议您手工标定。');
                end
            end
        end

        % Image clicked function: Image_4
        function Image_4Clicked(app, event)
            
            [file,path] = uigetfile('*.*');
            if isequal(file,0)
            else
                app.Img_4 = imread(fullfile(path,file));
                app.old_4 = app.Img_4;
                try
                    app.Img_4 = cut_square(app.Img_4);
                    app.Image_4.ImageSource = app.Img_4;
                catch
                    app.Image_4.ImageSource = app.Img_4;
                    warndlg('识别失败！建议您手工标定。');
                end
            end
        end

        % Image clicked function: Image_5
        function Image_5Clicked(app, event)
            
            [file,path] = uigetfile('*.*');
            if isequal(file,0)
            else
                app.Img_5 = imread(fullfile(path,file));
                app.old_5 = app.Img_5;
                %try
                    app.Img_5 = cut_square(app.Img_5);
                    app.Image_5.ImageSource = app.Img_5;
                %catch
                %    app.Image_5.ImageSource = app.Img_5;
                %    warndlg('识别失败！建议您手工标定。');
                %end
            end
        end

        % Image clicked function: Image_6
        function Image_6Clicked(app, event)
            
            [file,path] = uigetfile('*.*');
            if isequal(file,0)
            else
                app.Img_6 = imread(fullfile(path,file));
                app.old_6 = app.Img_6;
                try
                    app.Img_6 = cut_square(app.Img_6);
                    app.Image_6.ImageSource = app.Img_6;
                catch
                    app.Image_6.ImageSource = app.Img_6;
                    warndlg('识别失败！建议您手工标定。');
                end
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            % 更新color_mat
            temp = app.mat(1,:,:);
            temp = reshape(temp,3,3);
            temp = rot90(temp);
            app.mat(1,:,:) = temp;
            app.Img_1 = imrotate(app.Img_1,90);
            app.Image.ImageSource = app.Img_1;
        end

        % Button pushed function: Button_5
        function Button_8Pushed(app, event)
            % 更新color_mat
            temp = app.mat(2,:,:);
            temp = reshape(temp,3,3);
            temp = rot90(temp);
            app.mat(2,:,:) = temp;
            app.Img_2 = imrotate(app.Img_2,90);
            app.Image_2.ImageSource = app.Img_2;
        end

        % Button pushed function: Button_6
        function Button_12Pushed(app, event)
            % 更新color_mat
            temp = app.mat(3,:,:);
            temp = reshape(temp,3,3);
            temp = rot90(temp);
            app.mat(3,:,:) = temp;
            app.Img_3 = imrotate(app.Img_3,90);
            app.Image_3.ImageSource = app.Img_3;
        end

        % Button pushed function: Button_7
        function Button_14Pushed(app, event)
            temp = app.mat(4,:,:);
            temp = reshape(temp,3,3);
            temp = rot90(temp);
            app.mat(4,:,:) = temp;
            app.Img_4 = imrotate(app.Img_4,90);
            app.Image_4.ImageSource = app.Img_4;
        end

        % Button pushed function: Button_8
        function Button_6Pushed(app, event)
            temp = app.mat(5,:,:);
            temp = reshape(temp,3,3);
            temp = rot90(temp);
            app.mat(5,:,:) = temp;
            app.Img_5 = imrotate(app.Img_5,90);
            app.Image_5.ImageSource = app.Img_5;
        end

        % Button pushed function: Button_9
        function Button_10Pushed(app, event)
            temp = app.mat(6,:,:);
            temp = reshape(temp,3,3);
            temp = rot90(temp);
            app.mat(6,:,:) = temp;
            app.Img_6 = imrotate(app.Img_6,90);
            app.Image_6.ImageSource = app.Img_6;
        end

        % Button pushed function: Button
        function ColorPushed(app, event)

            % 触发颜色识别 
            color_detection;

            % 读取颜色识别的结果
            rgbString = fileread('rgb.txt');
            % 颜色表映射关系
            app.rgbTable = zeros(6,3);
            c = textscan(rgbString,'%f');
            c = c{1};
            app.rgbTable(1,:) = c(1:3);
            app.rgbTable(2,:) = c(4:6);
            app.rgbTable(3,:) = c(7:9);
            app.rgbTable(4,:) = c(10:12);
            app.rgbTable(5,:) = c(13:15);
            app.rgbTable(6,:) = c(16:18);

            % 从input.txt读取各个面的颜色代号信息
            faceColorsString = fileread('input.txt');
            c = textscan(faceColorsString,'%d');
            c = c{1};
            
            app.mat(1,:,:) = [c( 1: 3).';c( 4: 6).';c( 7: 9).'];
            app.mat(2,:,:) = [c(10:12).';c(13:15).';c(16:18).'];
            app.mat(3,:,:) = [c(19:21).';c(22:24).';c(25:27).'];
            app.mat(4,:,:) = [c(28:30).';c(31:33).';c(34:36).'];
            app.mat(5,:,:) = [c(37:39).';c(40:42).';c(43:45).'];
            app.mat(6,:,:) = [c(46:48).';c(49:51).';c(52:54).'];


            % 根据颜色字典建图
            app.Img_1 = zeros(900,900,3);
            app.Img_2 = zeros(900,900,3);
            app.Img_3 = zeros(900,900,3);
            app.Img_4 = zeros(900,900,3);
            app.Img_5 = zeros(900,900,3);
            app.Img_6 = zeros(900,900,3);
            
            app.Img_1 = app.func(1);
            app.Img_2 = app.func(2);
            app.Img_3 = app.func(3);
            app.Img_4 = app.func(4);
            app.Img_5 = app.func(5);
            app.Img_6 = app.func(6);

            app.Image.ImageSource = app.Img_1;
            app.Image_2.ImageSource = app.Img_2;
            app.Image_3.ImageSource = app.Img_3;
            app.Image_4.ImageSource = app.Img_4;
            app.Image_5.ImageSource = app.Img_5;
            app.Image_6.ImageSource = app.Img_6;
        end

        % Button pushed function: Button_2
        function CalculatePushed(app, event)

            fileId = fopen('input.txt','w');
            for i = 1 : 6
                fprintf(fileId,'%d %d %d\n',reshape(app.mat(i,:,:),3,3).');
            end
            fclose(fileId);
            % 传入求解程序，把步骤写入txt文件
            solvecube;
            % 打开旋转程序
            createCube(app);
        end

        % Callback function
        function calibrateButtonPushed(app, event)

        end

        % Callback function
        function calibrateButton_2Pushed(app, event)

        end

        % Callback function
        function calibrateButton_3Pushed(app, event)

        end

        % Callback function
        function calibrateButton_4Pushed(app, event)

        end

        % Callback function
        function calibrateButton_5Pushed(app, event)
            try
                app.Img_5 = cut_choose(app.old_5);
                app.Image_5.ImageSource = app.Img_5;
            catch
                warndlg('请重新操作！');
            end
        end

        % Callback function
        function calibrateButton_6Pushed(app, event)

        end

        % Callback function
        function Button_5Pushed(app, event)

        end

        % Callback function
        function Button_9Pushed(app, event)

        end

        % Callback function
        function Button_13Pushed(app, event)

        end

        % Callback function
        function Button_15Pushed(app, event)

        end

        % Callback function
        function Button_7Pushed(app, event)

        end

        % Callback function
        function Button_11Pushed(app, event)

        end

        % Button pushed function: Button_3
        function onePushed(app, event)
            % 一键传图
            [file, path] = uigetfile('*.*','MultiSelect', 'on');
            if iscell(file)
                [~,N] = size(file);
                if isequal(N,6)
                    app.Img_1 = imread(fullfile(path, file{1}));
                    app.old_1 = app.Img_1;
                    app.Img_2 = imread(fullfile(path, file{2}));
                    app.old_2 = app.Img_2;
                    app.Img_3 = imread(fullfile(path, file{3}));
                    app.old_3 = app.Img_3;
                    app.Img_4 = imread(fullfile(path, file{4}));
                    app.old_4 = app.Img_4;
                    app.Img_5 = imread(fullfile(path, file{5}));
                    app.old_5 = app.Img_5;
                    app.Img_6 = imread(fullfile(path, file{6}));
                    app.old_6 = app.Img_6;
                    flagInMainMlapp = true;
                    try
                        app.Img_1 = cut_square(app.Img_1);
                        app.Image.ImageSource = app.Img_1;
                    catch
                        flagInMainMlapp = false;
                        app.Image.ImageSource = app.Img_1;
                        warndlg('图片1识别失败！建议您手工标定。');
                    end

                    try
                        app.Img_2 = cut_square(app.Img_2);
                        app.Image_2.ImageSource = app.Img_2;
                    catch
                        flagInMainMlapp = false;
                        app.Image_2.ImageSource = app.Img_2;
                        warndlg('图片2识别失败！建议您手工标定。');
                    end

                    try
                        app.Img_3 = cut_square(app.Img_3);
                        app.Image_3.ImageSource = app.Img_3;
                    catch
                        flagInMainMlapp = false;
                        app.Image_3.ImageSource = app.Img_3;
                        warndlg('图片3识别失败！建议您手工标定。');
                    end

                    try
                        app.Img_4 = cut_square(app.Img_4);
                        app.Image_4.ImageSource = app.Img_4;
                    catch
                        flagInMainMlapp = false;
                        app.Image_4.ImageSource = app.Img_4;
                        warndlg('图片4识别失败！建议您手工标定。');
                    end

                    try
                        app.Img_5 = cut_square(app.Img_5);
                        app.Image_5.ImageSource = app.Img_5;
                    catch
                        flagInMainMlapp = false;
                        app.Image_5.ImageSource = app.Img_5;
                        warndlg('图片5识别失败！建议您手工标定。');
                    end

                    try
                        app.Img_6 = cut_square(app.Img_6);
                        app.Image_6.ImageSource = app.Img_6;
                    catch
                        flagInMainMlapp = false;
                        app.Image_6.ImageSource = app.Img_6;
                        warndlg('图片6识别失败！建议您手工标定。');
                    end
                    if flagInMainMlapp == true
                        imwrite(app.Img_1, "1.png");
                        imwrite(app.Img_2, "2.png");
                        imwrite(app.Img_3, "3.png");
                        imwrite(app.Img_4, "4.png");
                        imwrite(app.Img_5, "5.png");
                        imwrite(app.Img_6, "6.png");
%                         wrImg = imread(fullfile(path, file{1}));
%                         imwrite(wrImg,'1.jpg')
%                         wrImg = imread(fullfile(path, file{2}));
%                         imwrite(wrImg,'2.jpg')
%                         wrImg = imread(fullfile(path, file{3}));
%                         imwrite(wrImg,'3.jpg')
%                         wrImg = imread(fullfile(path, file{4}));
%                         imwrite(wrImg,'4.jpg')
%                         wrImg = imread(fullfile(path, file{5}));
%                         imwrite(wrImg,'5.jpg')
%                         wrImg = imread(fullfile(path, file{6}));
%                         imwrite(wrImg,'6.jpg')
                    end
                else
                    warndlg('不是6张图片，请重新上传！');
                end
            end
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            fileID = fopen('operation.txt','r');
            formatSpec = '%c %d\r';
            sizeA = [2 Inf];
            A = fscanf(fileID,formatSpec,sizeA);
            A=A';
            
            for i=1:size(A,1)
                
                fprintf('%c %d\n',A(i,1),A(i,2))
                if(A(i,1)=='U')
                    
                    moveg(app,1,A(i,2))
                end
                if(A(i,1)=='D')
                    
                    moveg(app,2,A(i,2))
                end
                if(A(i,1)=='L')
                    
                    moveg(app,3,A(i,2))
                end
                if(A(i,1)=='R')
                    
                    moveg(app,4,A(i,2))
                end
                if(A(i,1)=='B')
                    
                    moveg(app,5,A(i,2))
                end
                if(A(i,1)=='F')
                    
                    moveg(app,6,A(i,2)    )
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.HTML = uihtml(app.UIFigure);
            app.HTML.HTMLSource = 'back.html';
            app.Panel = uipanel(app.UIFigure);
            app.PrincipalInterestUIAxes = uiaxes(app.Panel);
            title(app.PrincipalInterestUIAxes, 'Solution Demonstration')
            app.PrincipalInterestUIAxes.XTick = [];
            app.PrincipalInterestUIAxes.YTick = [];
            app.PrincipalInterestUIAxes.YTickLabel = '';
            app.PrincipalInterestUIAxes.ZTick = [];
            app.startButton = uibutton(app.Panel, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = '1.jpg';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.Image_2.ImageSource = '2.jpg';
            app.Image_3 = uiimage(app.UIFigure);
            app.Image_3.ImageClickedFcn = createCallbackFcn(app, @Image_3Clicked, true);
            app.Image_3.ImageSource = '3.jpg';
            app.Image_4 = uiimage(app.UIFigure);
            app.Image_4.ImageClickedFcn = createCallbackFcn(app, @Image_4Clicked, true);
            app.Image_4.ImageSource = '4.jpg';
            app.Image_5 = uiimage(app.UIFigure);
            app.Image_5.ImageClickedFcn = createCallbackFcn(app, @Image_5Clicked, true);
            app.Image_5.ImageSource = '5.jpg';
            app.Image_6 = uiimage(app.UIFigure);
            app.Image_6.ImageClickedFcn = createCallbackFcn(app, @Image_6Clicked, true);
            app.Image_6.ImageSource = '6.jpg';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '魔方求解器';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'图片1'; '顶面'};
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = {'图片2'; '正面'};
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = {'图片3'; '底面'};
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = {'图片4'; '背面'};
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = {'图片5'; '左面'};
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = {'图片6'; '右面'};
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ColorPushed, true);
            app.Button.Text = '颜色识别';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @CalculatePushed, true);
            app.Button_2.Text = '魔方求解';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @onePushed, true);
            app.Button_3.Text = '魔方提取';
            app.Step16Step2Step3startoperationtxtLabel = uilabel(app.UIFigure);
            app.Step16Step2Step3startoperationtxtLabel.Text = {'使用说明：'; 'Step1: 可以点击魔方提取然'; '后依次选择 6 个图片上传图'; '片，也可以点击图片自行浏'; '览文件夹逐个上传'; 'Step2: 点击颜色识别进行识'; '别结束后会输出对应的识别'; 'Step3: 点击魔方求解进行求'; '解和演示，求解完成后会弹'; '出建模好的魔方，点击“start”'; '后进行求解过程演示，也可以'; '在"operation.txt"中查看结果。'};
            app.Step1Label = uilabel(app.UIFigure);
            app.Step1Label.Text = 'Step1:';
            app.Step2Label = uilabel(app.UIFigure);
            app.Step2Label.Text = 'Step2:';
            app.Step3Label = uilabel(app.UIFigure);
            app.Step3Label.Text = 'Step3:';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '翻转';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_5.Text = '翻转';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_6.Text = '翻转';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_7.Text = '翻转';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_8.Text = '翻转';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_9.Text = '翻转';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end